classdef TiplessGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        MediumSwitch            matlab.ui.control.Switch
        RadiusEditField         matlab.ui.control.NumericEditField
        CellRadiusRnmLabel      matlab.ui.control.Label
        OptionalArgumentsLabel  matlab.ui.control.Label
        RePlotButton            matlab.ui.control.Button
        StiffnessEditField      matlab.ui.control.NumericEditField
        StiffnessknNnmLabel     matlab.ui.control.Label
        FileControlsLabel       matlab.ui.control.Label
        AcceptButton            matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        SelectPathButton        matlab.ui.control.Button
        PathEditField           matlab.ui.control.EditField
        StartButton             matlab.ui.control.Button
        EndIndexSpinner         matlab.ui.control.Spinner
        EndIndexLabel           matlab.ui.control.Label
        StartIndexSpinner       matlab.ui.control.Spinner
        StartIndexLabel         matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        DataLabel               matlab.ui.control.Label
        Plot1                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        pathname = ''; % Pathname to be analyzed
        stiffness = 0.1; % Stiffness of the cantilever [nN/nm]
        cellRadius = []; % Cell Radius to use for all cells [nm]
        acceptButtonPress = false; % Placeholder for the accept button
        measurementMedium = 'liquid'; % The medium for the experiment
        
        % Plot Data Placeholders
        xdata = [];
        ydata = [];
        R = [];
        k = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the code library and all subfolders
            addpath(genpath('lib'));
            app.StiffnessEditField.Value = app.stiffness;
            app.MediumSwitch.Value = app.measurementMedium;
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            var = uigetdir(pwd,'Select a Directory for Analysis');
            if var ~= 0
                app.PathEditField.Editable = true;
                app.PathEditField.Value = var;
                app.PathEditField.Editable = false;
            end
            app.pathname = var;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~isempty(app.PathEditField.Value)
                app.StartButton.Text = "Running";
                app.StartButton.FontColor = [0 1 0];
                app.StartButton.Enable = false;
                app.Lamp.Color = [1 1 0];
                app.StiffnessEditField.Editable = false;
                app.RadiusEditField.Editable = false;
                app.SelectPathButton.Enable = false;
                
                try
                    tiplessAnalysis
                    app.StartButton.Text = "Complete";
                    app.Lamp.Color = [0 1 0];
                catch ERROR
                    disp(ERROR);
                    app.StartButton.Text = "Error";
                    app.Lamp.Color = [1 0 0];
                end
                
                pause(5);
                
                app.StartButton.Enable = true;
                app.StartButton.Text = "Start";
                app.StartButton.FontColor = [0 0 0];
                app.StiffnessEditField.Editable = true;
                app.RadiusEditField.Editable = true;
                app.SelectPathButton.Enable = true;
                
                app.xdata = [];
                app.ydata = [];
                app.R = [];
                app.k = [];
                
            else
                error("You didn't select a path! Please select a path and try again.")
            end
        end

        % Value changed function: StiffnessEditField
        function StiffnessEditFieldValueChanged(app, event)
            value = app.StiffnessEditField.Value;
            app.stiffness = value;
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            value = app.RadiusEditField.Value;
            app.cellRadius = value;
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            app.acceptButtonPress = true;
        end

        % Button pushed function: RePlotButton
        function RePlotButtonPushed(app, event)
            lb = app.StartIndexSpinner.Value;
            ub = app.EndIndexSpinner.Value;
            plotHandle = app.Plot1;
            
            updatePlot(plotHandle,app.xdata,app.ydata,lb,ub,app.R,app.k)
        end

        % Value changed function: StartIndexSpinner
        function StartIndexSpinnerValueChanged(app, event)
            value = app.StartIndexSpinner.Value;
            if value >= app.EndIndexSpinner.Value
                app.StartIndexSpinner.Value = app.EndIndexSpinner.Value-1;
            end
        end

        % Value changed function: EndIndexSpinner
        function EndIndexSpinnerValueChanged(app, event)
            value = app.EndIndexSpinner.Value;
            if value <= app.StartIndexSpinner.Value
                app.EndIndexSpinner.Value = app.StartIndexSpinner.Value+1;
            end
        end

        % Value changed function: MediumSwitch
        function MediumSwitchValueChanged(app, event)
            value = app.MediumSwitch.Value;
            app.measurementMedium = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartIndexLabel = uilabel(app.LeftPanel);
            app.StartIndexLabel.Enable = 'off';
            app.StartIndexLabel.Text = 'Start Index:';
            app.StartIndexSpinner = uispinner(app.LeftPanel);
            app.StartIndexSpinner.Limits = [1 Inf];
            app.StartIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @StartIndexSpinnerValueChanged, true);
            app.StartIndexSpinner.Enable = 'off';
            app.StartIndexSpinner.Value = 1;
            app.EndIndexLabel = uilabel(app.LeftPanel);
            app.EndIndexLabel.Enable = 'off';
            app.EndIndexLabel.Text = 'End Index:';
            app.EndIndexSpinner = uispinner(app.LeftPanel);
            app.EndIndexSpinner.Limits = [1 Inf];
            app.EndIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @EndIndexSpinnerValueChanged, true);
            app.EndIndexSpinner.Enable = 'off';
            app.EndIndexSpinner.Value = 1;
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PathEditField = uieditfield(app.LeftPanel, 'text');
            app.PathEditField.Editable = 'off';
            app.SelectPathButton = uibutton(app.LeftPanel, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.Lamp = uilamp(app.LeftPanel);
            app.AcceptButton = uibutton(app.LeftPanel, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Enable = 'off';
            app.AcceptButton.Text = 'Accept';
            app.FileControlsLabel = uilabel(app.LeftPanel);
            app.FileControlsLabel.Text = 'File Controls:';
            app.StiffnessknNnmLabel = uilabel(app.LeftPanel);
            app.StiffnessknNnmLabel.Text = 'Stiffness (k) [nN/nm]:';
            app.StiffnessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StiffnessEditField.LowerLimitInclusive = 'off';
            app.StiffnessEditField.UpperLimitInclusive = 'off';
            app.StiffnessEditField.Limits = [0 Inf];
            app.StiffnessEditField.ValueChangedFcn = createCallbackFcn(app, @StiffnessEditFieldValueChanged, true);
            app.StiffnessEditField.Tooltip = {'Cantilever Stiffness in units of nN/nm. If analyzing text files, this quantity is used inside the script. If analyzing Bruker files, the value is ignored and the stiffness inside the Bruker file is used.'};
            app.StiffnessEditField.Value = 0.1;
            app.RePlotButton = uibutton(app.LeftPanel, 'push');
            app.RePlotButton.ButtonPushedFcn = createCallbackFcn(app, @RePlotButtonPushed, true);
            app.RePlotButton.Enable = 'off';
            app.RePlotButton.Text = 'Re-Plot';
            app.OptionalArgumentsLabel = uilabel(app.LeftPanel);
            app.OptionalArgumentsLabel.Text = 'Optional Arguments:';
            app.CellRadiusRnmLabel = uilabel(app.LeftPanel);
            app.CellRadiusRnmLabel.Text = 'Cell Radius (R) [nm]:';
            app.RadiusEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiusEditField.UpperLimitInclusive = 'off';
            app.RadiusEditField.Limits = [0 Inf];
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.Tooltip = {'The cell radius. If this entry is left as zero, it is ignored and the user is prompted for a cell radius every time a new file is analyzed. If the value is nonzero, that radius value is used for ALL cells.'};
            app.MediumSwitch = uiswitch(app.LeftPanel, 'slider');
            app.MediumSwitch.Items = {'Correct Curve', 'Don''t Correct'};
            app.MediumSwitch.ItemsData = {'liquid', 'air'};
            app.MediumSwitch.ValueChangedFcn = createCallbackFcn(app, @MediumSwitchValueChanged, true);
            app.MediumSwitch.Value = 'liquid';
            app.RightPanel = uipanel(app.GridLayout);
            app.Plot1 = uiaxes(app.RightPanel);
            title(app.Plot1, 'Data Preview')
            xlabel(app.Plot1, 'X')
            ylabel(app.Plot1, 'Y')
            zlabel(app.Plot1, 'Z')
            app.DataLabel = uilabel(app.RightPanel);
            app.DataLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TiplessGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end